classdef Modelacion_de_Placas_Final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LimpiarGraciasButton            matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        DatosdeSimulacionTab            matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        IntervalodetiemposEditField     matlab.ui.control.NumericEditField
        IntervalodetiemposEditFieldLabel  matlab.ui.control.Label
        CargaelectricadelagotadesangreCEditField  matlab.ui.control.NumericEditField
        CargaelectricadelagotadesangreCEditFieldLabel  matlab.ui.control.Label
        MasadelagotadesangreKgEditField  matlab.ui.control.NumericEditField
        MasadelagotadesangreKgEditFieldLabel  matlab.ui.control.Label
        VelocidaddesalidaenymsEditField  matlab.ui.control.NumericEditField
        VelocidaddesalidaenymsEditFieldLabel  matlab.ui.control.Label
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        VectoresdeCampoElectricoTab     matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        GraficarButton                  matlab.ui.control.Button
        DistanciaentreplacasmEditField  matlab.ui.control.NumericEditField
        DistanciaentreplacasmEditFieldLabel  matlab.ui.control.Label
        PotencialenplacanegativaVEditField  matlab.ui.control.NumericEditField
        PotencialenplacanegativaVEditFieldLabel  matlab.ui.control.Label
        PotencialenplacapositivaVEditField  matlab.ui.control.NumericEditField
        PotencialenplacapositivaVEditFieldLabel  matlab.ui.control.Label
        LongituddeplacanegativamEditField  matlab.ui.control.NumericEditField
        LongituddeplacanegativamLabel   matlab.ui.control.Label
        LongituddeplacapositivamEditField  matlab.ui.control.NumericEditField
        LongituddeplacapositivamLabel   matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GraficarButton
        function Graficar_Pushed(app, event)
            L_positiva= app.LongituddeplacapositivamEditField.Value;
            L_negativa= app.LongituddeplacanegativamEditField.Value;
            v_positivo= app.PotencialenplacapositivaVEditField.Value;
            v_negativo= app.PotencialenplacanegativaVEditField.Value;
            distancia= app.DistanciaentreplacasmEditField.Value;
            q_sangre= app.CargaelectricadelagotadesangreCEditField.Value;
            m_sangre= app.MasadelagotadesangreKgEditField.Value;
            v0_sangre= [0,app.VelocidaddesalidaenymsEditField.Value];
          
            h= app.IntervalodetiemposEditField.Value;
            
            r0_sangre=[distancia/2,max(L_negativa,L_positiva)];


            [trayectoria,  X, Y, Ex, Ey, x1, y1, x2, y2]= Simulacion_Placas_Cargadas(L_positiva, L_negativa, distancia, v_positivo, v_negativo,q_sangre, m_sangre, v0_sangre, r0_sangre, h);
            
            x_trayectoria = trayectoria(1, :);
            y_trayectoria = trayectoria(2, :);


% Interpolar el campo eléctrico en la trayectoria
            Ex_sobre_gota = interp2(X, Y, Ex, x_trayectoria, y_trayectoria, 'linear', 0);
            Ey_sobre_gota = interp2(X, Y, Ey, x_trayectoria, y_trayectoria, 'linear', 0);
            
            cla(app.UIAxes);

            % Graficar las placas
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, x1, y1, 'k', 'LineWidth', 3,'Color','r');
            plot(app.UIAxes, x2, y2, 'k', 'LineWidth', 3,'Color','b');
        
            % Graficar el campo eléctrico
            quiver(app.UIAxes, X, Y, Ex, Ey, 'b');
        
            % Graficar la trayectoria de la gota
            plot(app.UIAxes, trayectoria(1,:), trayectoria(2,:), 'r', 'LineWidth', 2);
        
            % Graficar los puntos de inicio y fin
            plot(app.UIAxes, trayectoria(1,1), trayectoria(2,1), 'go', 'MarkerFaceColor', 'g'); % inicio
            plot(app.UIAxes, trayectoria(1,end), trayectoria(2,end), 'rx', 'MarkerSize', 10);   % fin
        
            % Ajustar leyenda
            legend(app.UIAxes, {'Placa 1', 'Placa 2', 'Campo E', 'Trayectoria', 'Inicio', 'Fin'});
        
            % Configurar la visualización
            axis(app.UIAxes, 'equal');
            grid(app.UIAxes, 'on');
            hold(app.UIAxes, 'off');

            plot(app.UIAxes2,x_trayectoria,Ex_sobre_gota)
            plot(app.UIAxes3,y_trayectoria,Ey_sobre_gota)

            
            numFilas = length(x_trayectoria);


            datosTabla = [num2cell(x_trayectoria(:)), num2cell(y_trayectoria(:))];


            app.UITable.Data = datosTabla;


            app.UITable.ColumnName = {'Coordenada X', 'Coordenada Y'};


        end

        % Button pushed function: LimpiarGraciasButton
        function Limpiar_Graficas(app, event)
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LongituddeplacapositivamLabel = uilabel(app.UIFigure);
            app.LongituddeplacapositivamLabel.Text = 'Longitud de placa positiva(m)';
            app.LongituddeplacapositivamEditField = uieditfield(app.UIFigure, 'numeric');
            app.LongituddeplacapositivamEditField.Limits = [0.01 1];
            app.LongituddeplacapositivamEditField.Value = 0.01;
            app.LongituddeplacanegativamLabel = uilabel(app.UIFigure);
            app.LongituddeplacanegativamLabel.Text = 'Longitud de placa negativa(m)';
            app.LongituddeplacanegativamEditField = uieditfield(app.UIFigure, 'numeric');
            app.LongituddeplacanegativamEditField.Limits = [0.01 1];
            app.LongituddeplacanegativamEditField.Value = 0.01;
            app.PotencialenplacapositivaVEditFieldLabel = uilabel(app.UIFigure);
            app.PotencialenplacapositivaVEditFieldLabel.Text = 'Potencial en placa positiva(V)';
            app.PotencialenplacapositivaVEditField = uieditfield(app.UIFigure, 'numeric');
            app.PotencialenplacapositivaVEditField.Limits = [0 Inf];
            app.PotencialenplacanegativaVEditFieldLabel = uilabel(app.UIFigure);
            app.PotencialenplacanegativaVEditFieldLabel.Text = 'Potencial en placa negativa (V)';
            app.PotencialenplacanegativaVEditField = uieditfield(app.UIFigure, 'numeric');
            app.PotencialenplacanegativaVEditField.Limits = [-Inf 0];
            app.DistanciaentreplacasmEditFieldLabel = uilabel(app.UIFigure);
            app.DistanciaentreplacasmEditFieldLabel.Text = 'Distancia entre placas (m)';
            app.DistanciaentreplacasmEditField = uieditfield(app.UIFigure, 'numeric');
            app.DistanciaentreplacasmEditField.Limits = [0.002 0.2];
            app.DistanciaentreplacasmEditField.Value = 0.002;
            app.GraficarButton = uibutton(app.UIFigure, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @Graficar_Pushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DatosdeSimulacionTab = uitab(app.TabGroup);
            app.DatosdeSimulacionTab.Title = 'Datos de Simulacion';
            app.UIAxes2 = uiaxes(app.DatosdeSimulacionTab);
            title(app.UIAxes2, 'Campo Electrico en direccion x')
            xlabel(app.UIAxes2, 'Posicion x (m)')
            ylabel(app.UIAxes2, 'Campo electrico Ex (V/m)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.DatosdeSimulacionTab);
            title(app.UIAxes3, 'Campo electrico en direccion y')
            xlabel(app.UIAxes3, 'Posicion y(m)')
            ylabel(app.UIAxes3, 'Campo Electrico Ey (V/m)')
            zlabel(app.UIAxes3, 'Z')
            app.VelocidaddesalidaenymsEditFieldLabel = uilabel(app.DatosdeSimulacionTab);
            app.VelocidaddesalidaenymsEditFieldLabel.Text = 'Velocidad de salida en  y (m/s)';
            app.VelocidaddesalidaenymsEditField = uieditfield(app.DatosdeSimulacionTab, 'numeric');
            app.VelocidaddesalidaenymsEditField.Limits = [-Inf -1];
            app.VelocidaddesalidaenymsEditField.Value = -1;
            app.MasadelagotadesangreKgEditFieldLabel = uilabel(app.DatosdeSimulacionTab);
            app.MasadelagotadesangreKgEditFieldLabel.Text = 'Masa de la gota de sangre  (Kg)';
            app.MasadelagotadesangreKgEditField = uieditfield(app.DatosdeSimulacionTab, 'numeric');
            app.MasadelagotadesangreKgEditField.Limits = [1e-07 5e-05];
            app.MasadelagotadesangreKgEditField.Value = 1e-06;
            app.CargaelectricadelagotadesangreCEditFieldLabel = uilabel(app.DatosdeSimulacionTab);
            app.CargaelectricadelagotadesangreCEditFieldLabel.Text = 'Carga electrica de la gota de sangre  (C)';
            app.CargaelectricadelagotadesangreCEditField = uieditfield(app.DatosdeSimulacionTab, 'numeric');
            app.CargaelectricadelagotadesangreCEditField.Limits = [-5e-13 5e-13];
            app.CargaelectricadelagotadesangreCEditField.Value = 5e-13;
            app.IntervalodetiemposEditFieldLabel = uilabel(app.DatosdeSimulacionTab);
            app.IntervalodetiemposEditFieldLabel.Text = 'Intervalo de tiempo (s)';
            app.IntervalodetiemposEditField = uieditfield(app.DatosdeSimulacionTab, 'numeric');
            app.IntervalodetiemposEditField.Limits = [1e-07 0.001];
            app.IntervalodetiemposEditField.Value = 0.0001;
            app.UITable = uitable(app.DatosdeSimulacionTab);
            app.UITable.ColumnName = {'Cordenada X'; 'Cordenada Y'};
            app.UITable.RowName = {};
            app.VectoresdeCampoElectricoTab = uitab(app.TabGroup);
            app.VectoresdeCampoElectricoTab.Title = 'Vectores de Campo Electrico';
            app.UIAxes = uiaxes(app.VectoresdeCampoElectricoTab);
            title(app.UIAxes, 'Modelo de Placas')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.LimpiarGraciasButton = uibutton(app.UIFigure, 'push');
            app.LimpiarGraciasButton.ButtonPushedFcn = createCallbackFcn(app, @Limpiar_Graficas, true);
            app.LimpiarGraciasButton.Text = 'Limpiar Gracias';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Modelacion_de_Placas_Final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end